classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LoadImageButton      matlab.ui.control.Button
        OutputTextArea       matlab.ui.control.TextArea
        OutputTextAreaLabel  matlab.ui.control.Label
        Image                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function sim = TemplateMatching(~, image, letter)
            chars_dir = 'images/characters';
            chars_path = dir(fullfile(chars_dir, letter, '*.bmp'));
            [rows, cols] = size(image);
            
            sim = 0;
            for k = 1 : length(chars_path)
                fullpath = fullfile(chars_dir, letter, chars_path(k).name);
                f = imread(fullpath);
                r = imresize(f, [rows cols]);
                
                match = 0;
                for i = 1 : rows
                    for j = 1 : cols
                        if (image(i, j) == r(i, j))
                            match = match + 1;
                        end
                    end
                end
                
                curr_sim = double(match/(rows*cols));
                if curr_sim > sim
                    sim = curr_sim;
                end
            end
        end
        
        function BBfinal = GetCharactersRect(~, B)
            % Find bounding box of remaining objects
            Iprops = regionprops(B, 'BoundingBox', 'Image');
            BB1 = cat(1, Iprops.BoundingBox);
        
            % remove objects that likely not a character by calculating
            % the percentage of non zero pixels in the object image
            BB2  = [];
            for k = 1 : size(BB1, 1)
                BB = BB1(k, :);
                w = BB(3);
                h = BB(4);
                pixels = w * h;
                non_zero_pixels = nnz(B(BB(2):BB(2)+h, BB(1):BB(1)+w));
        
                if double(non_zero_pixels/pixels) < 0.3
                    continue;
                end
        
                c = BB2;
                BB2 = [c; BB];
            end
        
            % remove objects that likely not a character by using
            % ratio of width and height of the object
            BB3 = [];
            for k = 1 : size(BB2, 1)
                BB = BB2(k, :);
                w = BB(3);
                h = BB(4);
                ratio = double(h/w);
        
                if not(ratio >= 1 && ratio < 6)
                    continue;
                end
        
                c = BB3;
                BB3 = [c; BB];
            end
        
            % remove other characters that is not the plat number
            % by comparing the height assuming that plat number
            % will be alwayst be the tallest obect
            height_max = max(BB3(:, 4));
            BBfinal = [];
            for k = 1 : size(BB3, 1)
                BB = BB3(k, :);
                h = BB(4);
        
                if double(h/height_max) < 0.7
                    continue;
                end
        
                c = BBfinal;
                BBfinal = [c; BB];
            end
        end
        
        function PlatNumberRecognition(app, imagePath)
            % load image
            f = imread(imagePath);
            
            % keep images the same size
            f = imresize(f, [400 NaN]);
        
            % convert image to grayscale
            g = rgb2gray(f);
            % apply median filter
            g = medfilt2(g, [3 3]);
            
            % find edges by subtracting result of
            % dilate and erode
            se = strel('disk', 1);
            gd = imdilate(g, se);
            ge = imerode(g, se);
            gdiff = imsubtract(gd, ge);
        
            % scale values to 0 - 1 range
            gdiff = mat2gray(gdiff);
            gdiff = conv2(gdiff, [1 1; 1 1]);
        
            % binarize image
            B = imbinarize(gdiff);
            % eliminate pixels that connected to border
            B = imclearborder(B);
        
            % eliminate possible lines that likely not part of the region of interest
            er = imerode(B, strel('line', 100, 0));
            out1 = imsubtract(B, er);
        
            % filling the regions
            F = imfill(out1, 'holes');
        
            % thinning the image
            H = bwmorph(F, 'thin', 1);
            H = imerode(H, strel('line', 3, 90));
        
            % Selecting all the regions that are of pixel area more than 100
            final = bwareaopen(H, 100);
            
            % Get characters bounding box
            BBs = GetCharactersRect(app, final);
            
            % Matching each object with template letters
            chars = ['A' 'B' 'C' 'D' 'E' 'F' 'G' 'H' 'I' 'J' 'K' 'L' 'M' 'N' 'O' 'P' 'Q' 'R' 'S' 'T' 'U' 'V' 'W' 'X' 'Y' 'Z' '1' '2' '3' '4' '5' '6' '7' '8' '9' '0'];
            plat_numbers = '';
            for k = 1 : size(BBs, 1)
               BB = BBs(k, :);
               letter = 'A';
               prob = TemplateMatching(app, imcrop(final, BB), 'A');
               for i = 2 : length(chars)
                   char = chars(i);
                   curr_prob = TemplateMatching(app, imcrop(final, BB), char);
                   if curr_prob > prob
                       letter = char;
                       prob = curr_prob;
                   end
               end
               
               plat_numbers = strcat(plat_numbers, letter);
            end
            
%             display play number recognition result
            app.OutputTextArea.Value = plat_numbers;
            
            % draw bounding box
            imshow(f, 'parent', app.Image);
            for k = 1 : size(BBs, 1)
                rectangle(app.Image, 'Position', BBs(k, :), 'EdgeColor', 'r', 'LineWidth', 2); 
            end
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               PlatNumberRecognition(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiaxes(app.UIFigure);
            title(app.Image, 'Input')
            app.Image.XTick = [];
            app.Image.YTick = [];
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end